classdef appVisualition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CourbesafficherTab              matlab.ui.container.Tab
        CourbesafficherListBoxLabel     matlab.ui.control.Label
        CourbesafficherListBox          matlab.ui.control.ListBox
        AffichageTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        REditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        DynamiqueruptureEditFieldLabel  matlab.ui.control.Label
        DynamiqueruptureEditField       matlab.ui.control.NumericEditField
        VariancebruitEditFieldLabel     matlab.ui.control.Label
        VariancebruitEditField          matlab.ui.control.NumericEditField
        GridLayout5                     matlab.ui.container.GridLayout
        QEditFieldLabel                 matlab.ui.control.Label
        QEditField                      matlab.ui.control.NumericEditField
        SeuilEditFieldLabel             matlab.ui.control.Label
        SeuilEditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Data_y1_r;
        Data_y1_b;
        Data_y1_est;
        Time_y1;
        
        Data_y2_r;
        Data_y2_b;
        Data_y2_est;
        Time_y2;
        
        Data_y3_r;
        Data_y3_b;
        Data_y3_est;
        Time_y3;
        
        Data_y4_r;
        Data_y4_b;
        Data_y4_est;
        Time_y4;
        
        Affiche_courbes_bruites;
        Nombre_courbes;
        R;
        Q;
        seuil;
        H;
        V; % variance du bruit
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CourbesafficherListBox
        function CourbesafficherListBoxValueChanged(app, event)
            value = app.CourbesafficherListBox.Value;
            taille = length(app.GridLayout.Children());
            if taille > 2
                app.GridLayout.Children(3:end).delete(); % On supprime les éléments de la grille déjà existants
            end
            app.Q=0.000000001;
            app.R=1.296e-5;
            app.V = app.R;
            app.H=1;
            app.seuil=20;
            if length(value)==1
                app.Nombre_courbes=1;
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(value)==2
                app.Nombre_courbes=2;
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(value{2},app.V,app.H,app,2);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(value)==3
                app.Nombre_courbes=3;
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(value{2},app.V,app.H,app,2);
                [app.Data_y3_r,app.Data_y3_b]=valueToCourbes(value{3},app.V,app.H,app,3);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(value)==4 || length(value)>4
                app.Nombre_courbes=4;
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(value{2},app.V,app.H,app,2);
                [app.Data_y3_r,app.Data_y3_b]=valueToCourbes(value{3},app.V,app.H,app,3);
                [app.Data_y4_r,app.Data_y4_b]=valueToCourbes(value{4},app.V,app.H,app,4);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            else
                app.Nombre_courbes=0;
            end
        end

        % Callback function
        function QKnobValueChanged(app, event)
            value = app.QKnob.Value;
            app.Q = value;
            actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
        end

        % Callback function
        function RKnobValueChanged(app, event)
            value = app.RKnob.Value;
            app.R = value;
            actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
        end

        % Callback function
        function SeuilKnobValueChanged(app, event)
            value = app.SeuilKnob.Value;
            app.seuil = value;
            actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            app.R = value;
            actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
        end

        % Value changed function: QEditField
        function QEditFieldValueChanged(app, event)
            value = app.QEditField.Value;
            app.Q = value;
            actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
        end

        % Value changed function: SeuilEditField
        function SeuilEditFieldValueChanged(app, event)
            value = app.SeuilEditField.Value;
            app.seuil =value;
            actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            
        end

        % Callback function
        function QKnobValueChanged2(app, event)
            value = app.QKnob.Value;
            app.Q = value;
            actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            
        end

        % Value changed function: DynamiqueruptureEditField
        function DynamiqueruptureEditFieldValueChanged(app, event)
            valueH = app.DynamiqueruptureEditField.Value;
            value = app.CourbesafficherListBox.Value;
            app.H = valueH;
            if length(value)==1
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(value)==2
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(value{2},app.V,app.H,app,2);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(value)==3
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(value{2},app.V,app.H,app,2);
                [app.Data_y3_r,app.Data_y3_b]=valueToCourbes(value{3},app.V,app.H,app,3);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(value)==4 || length(value)>4
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(value{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(value{2},app.V,app.H,app,2);
                [app.Data_y3_r,app.Data_y3_b]=valueToCourbes(value{3},app.V,app.H,app,3);
                [app.Data_y4_r,app.Data_y4_b]=valueToCourbes(value{4},app.V,app.H,app,4);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            end
            
        end

        % Value changed function: VariancebruitEditField
        function VariancebruitEditFieldValueChanged(app, event)
            value = app.VariancebruitEditField.Value;
            valueC = app.CourbesafficherListBox.Value;
            app.V = value;
            if length(valueC)==1
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(valueC{1},app.V,app.H,app,1);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(valueC)==2
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(valueC{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(valueC{2},app.V,app.H,app,2);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(valueC)==3
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(valueC{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(valueC{2},app.V,app.H,app,2);
                [app.Data_y3_r,app.Data_y3_b]=valueToCourbes(valueC{3},app.V,app.H,app,3);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            elseif length(valueC)==4 || length(value)>4
                [app.Data_y1_r,app.Data_y1_b]=valueToCourbes(valueC{1},app.V,app.H,app,1);
                [app.Data_y2_r,app.Data_y2_b]=valueToCourbes(valueC{2},app.V,app.H,app,2);
                [app.Data_y3_r,app.Data_y3_b]=valueToCourbes(valueC{3},app.V,app.H,app,3);
                [app.Data_y4_r,app.Data_y4_b]=valueToCourbes(valueC{4},app.V,app.H,app,4);
                actualiserCourbes(app.Nombre_courbes,app.Q,app.R,app.seuil,app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CourbesafficherTab = uitab(app.TabGroup);
            app.CourbesafficherTab.Title = 'Courbes à afficher';
            app.CourbesafficherListBoxLabel = uilabel(app.CourbesafficherTab);
            app.CourbesafficherListBoxLabel.Text = 'Courbes à afficher';
            app.CourbesafficherListBox = uilistbox(app.CourbesafficherTab);
            app.CourbesafficherListBox.Items = {'échelon (modèle)', 'rampe (modèle)', 'constant (modèle)', 'rampeEtRupture (modèle)', 'pointZero (réel)', 'pointEtalon (réel)', 'creneaux (réel)'};
            app.CourbesafficherListBox.Multiselect = 'on';
            app.CourbesafficherListBox.ValueChangedFcn = createCallbackFcn(app, @CourbesafficherListBoxValueChanged, true);
            app.CourbesafficherListBox.Value = {'pointZero (réel)'};
            app.AffichageTab = uitab(app.TabGroup);
            app.AffichageTab.Title = 'Affichage';
            app.GridLayout = uigridlayout(app.AffichageTab);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.REditFieldLabel = uilabel(app.GridLayout4);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.GridLayout4, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.DynamiqueruptureEditFieldLabel = uilabel(app.GridLayout4);
            app.DynamiqueruptureEditFieldLabel.Text = 'Dynamique (rupture)';
            app.DynamiqueruptureEditField = uieditfield(app.GridLayout4, 'numeric');
            app.DynamiqueruptureEditField.ValueChangedFcn = createCallbackFcn(app, @DynamiqueruptureEditFieldValueChanged, true);
            app.VariancebruitEditFieldLabel = uilabel(app.GridLayout4);
            app.VariancebruitEditFieldLabel.Text = 'Variance bruit';
            app.VariancebruitEditField = uieditfield(app.GridLayout4, 'numeric');
            app.VariancebruitEditField.ValueChangedFcn = createCallbackFcn(app, @VariancebruitEditFieldValueChanged, true);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.QEditFieldLabel = uilabel(app.GridLayout5);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.GridLayout5, 'numeric');
            app.QEditField.ValueChangedFcn = createCallbackFcn(app, @QEditFieldValueChanged, true);
            app.SeuilEditFieldLabel = uilabel(app.GridLayout5);
            app.SeuilEditFieldLabel.Text = 'Seuil';
            app.SeuilEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SeuilEditField.ValueChangedFcn = createCallbackFcn(app, @SeuilEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appVisualition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end